classdef mecanicadefluidos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        f                            matlab.ui.control.NumericEditField
        fEditFieldLabel              matlab.ui.control.Label
        Reynolds                     matlab.ui.control.NumericEditField
        ReynoldsEditFieldLabel       matlab.ui.control.Label
        regimen                      matlab.ui.control.EditField
        TipoderegimenEditFieldLabel  matlab.ui.control.Label
        Velocidad                    matlab.ui.control.NumericEditField
        VelocidadmsEditFieldLabel    matlab.ui.control.Label
        Diametro                     matlab.ui.control.NumericEditField
        DiametrommEditFieldLabel     matlab.ui.control.Label
        Temperatura                  matlab.ui.control.NumericEditField
        TemperaturaCEditFieldLabel   matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        CalcularButton               matlab.ui.control.Button
        TipodetuberaDropDownLabel    matlab.ui.control.Label
        TipodetuberaDropDown         matlab.ui.control.DropDown
        ProgramaparacalcularelfactordefriccindeDarcyfEquipoGGnojgLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            %Se declaran las entradas 
            Temp = app.Temperatura.Value;
            T = Temp;
            diamet = app.Diametro.Value * 1000;
            velocidad = app.Velocidad.Value;
            densidad = 1.6e-05*T.^3 + -0.005963*T.^2 + 0.02056*T + 1000;
            viscosidad = (-2.717e-09*T.^3 + 6.052e-07*T.^2 + -4.822e-05*T + 0.00176)*1000
            app.Reynolds.Value = ((velocidad*densidad)/1000*viscosidad);%esto no representa  el verdadero valor de  reynolds, solo estoy haciendo una prueba
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ProgramaparacalcularelfactordefriccindeDarcyfEquipoGGnojgLabel = uilabel(app.UIFigure);
            app.ProgramaparacalcularelfactordefriccindeDarcyfEquipoGGnojgLabel.Text = 'Programa para calcular el  factor de fricción de Darcy, f. Equipo: GG no jg';
            app.TipodetuberaDropDown = uidropdown(app.UIFigure);
            app.TipodetuberaDropDown.Items = {'vidrio', 'cobre', 'PVC', 'Acero Inoxidable', 'Acero al Carbón', 'Hierro dulce', 'Hierro Galvanizado', 'Cemento Suavizado', 'Concreto'};
            app.TipodetuberaDropDown.Value = 'Concreto';
            app.TipodetuberaDropDownLabel = uilabel(app.UIFigure);
            app.TipodetuberaDropDownLabel.Text = 'Tipo de tubería';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'igrupo.jpeg';
            app.TemperaturaCEditFieldLabel = uilabel(app.UIFigure);
            app.TemperaturaCEditFieldLabel.Text = 'Temperatura (ºC)';
            app.Temperatura = uieditfield(app.UIFigure, 'numeric');
            app.DiametrommEditFieldLabel = uilabel(app.UIFigure);
            app.DiametrommEditFieldLabel.Text = 'Diametro (mm)';
            app.Diametro = uieditfield(app.UIFigure, 'numeric');
            app.VelocidadmsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadmsEditFieldLabel.Text = 'Velocidad (m/s)';
            app.Velocidad = uieditfield(app.UIFigure, 'numeric');
            app.TipoderegimenEditFieldLabel = uilabel(app.UIFigure);
            app.TipoderegimenEditFieldLabel.Text = 'Tipo de regimen';
            app.regimen = uieditfield(app.UIFigure, 'text');
            app.regimen.Editable = 'off';
            app.ReynoldsEditFieldLabel = uilabel(app.UIFigure);
            app.ReynoldsEditFieldLabel.Text = 'Reynolds';
            app.Reynolds = uieditfield(app.UIFigure, 'numeric');
            app.Reynolds.Editable = 'off';
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.f = uieditfield(app.UIFigure, 'numeric');
            app.f.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mecanicadefluidos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end