classdef tuto_axes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        AcordeonButton  matlab.ui.control.Button
        TortaButton     matlab.ui.control.Button
        SenoButton      matlab.ui.control.Button
        Axes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SenoButton
        function SenoButtonPushed(app, event)
            cla(app.Axes,"reset"); %limpiamos el grafico al principio de la ejecución
            t = 0:0.001:2*pi; %se define el vector que se va a graficar
            seno = sin(2*t); % se define la variable a graficar
            plot(app.Axes,t,seno);  %se manda a llamar a la funcion cuando se da click en el boton
        end

        % Callback function
        function AxesButtonDown(app, event)
            
        end

        % Button pushed function: TortaButton
        function TortaButtonPushed(app, event)
            cla(app.Axes,"reset"); %limpiamos el grafico al principio de la ejecución
            datos = [40,15,45];
            pie3(app.Axes,datos,[1,1,0]);
        end

        % Button pushed function: AcordeonButton
        function AcordeonButtonPushed(app, event)
            cla(app.Axes,"reset"); %limpiamos el grafico al principio de la ejecución
            t = 0:pi/50:10*pi;
            st = sin(t); %variable que almacena el seno de t
            cs = cos(t); %variable que almacena el coseno de t
            plot3(app.Axes,st,cs,t);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes = uiaxes(app.UIFigure);
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.SenoButton = uibutton(app.UIFigure, 'push');
            app.SenoButton.ButtonPushedFcn = createCallbackFcn(app, @SenoButtonPushed, true);
            app.SenoButton.Text = 'Seno';
            app.TortaButton = uibutton(app.UIFigure, 'push');
            app.TortaButton.ButtonPushedFcn = createCallbackFcn(app, @TortaButtonPushed, true);
            app.TortaButton.Text = 'Torta';
            app.AcordeonButton = uibutton(app.UIFigure, 'push');
            app.AcordeonButton.ButtonPushedFcn = createCallbackFcn(app, @AcordeonButtonPushed, true);
            app.AcordeonButton.Text = 'Acordeon';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tuto_axes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end